classdef video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MeditationoclockLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AudioPlayer audioplayer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath 'D:\Neuro\Projeto\MindSpark\functions\sigprocfunc'
            addpath 'D:\Neuro\Projeto\MindSpark'
            axes_handle = app.UIAxes;
            image_file = 'meditação.png';
            imshow(image_file, 'Parent', axes_handle);
            drawnow

            mu = MuseUdp();
            
            epoch_length = 30;
            Fs = 256;
            
            vector_tp9 = [];
            vector_af7 = [];
            vector_af8 = [];
            vector_tp10 = [];
            
            EalphaGamma_tp = [];
            EalphaBetaASM_tp = [];
            EEbeta_tp = [];
            EEgamma_tp = [];
            EEtheta_tp = [];
            DASMalpha_tp = [];
            PSD_alpha_tp = [];
            
            EalphaGamma_af = [];
            EalphaBetaASM_af = [];
            EEbeta_af = [];
            EEgamma_af = [];
            EEtheta_af = [];
            DASMalpha_af = [];
            PSD_alpha_af = [];
            
            alpha_freq = [8, 13];       
            theta_freq = [4, 8];
            beta_freq = [14, 30];
            gamma_freq = [30; 50];
            
            runtime = evalin('base', 'runtime');

            audio_player = audioplayer(audioread('audio_maior.mp3'), 44100);
            play(audio_player);
            tic
            while toc<runtime
                play(audio_player);
                [data, ~, success] = mu.get_eeg_sample();
                if success
                    vector_tp9(end+1) = data(1)-data(5);
                    vector_af7(end+1) = data(2)-data(5);
                    vector_af8(end+1) = data(3)-data(5);
                    vector_tp10(end+1) = data(4)-data(5);
                end
            end
          
            signal_tp = (vector_tp9 + vector_tp10) ./2;
            signal_af = (vector_af8 + vector_af7) ./2;
            
            [alpha_signaltp, theta_signaltp, beta_signaltp, gamma_signaltp] = signal_decompose(signal_tp, Fs);
            [alpha_signalaf, theta_signalaf, beta_signalaf, gamma_signalaf] = signal_decompose(signal_af, Fs);

            EalphaGamma_af = (signal_energy(alpha_signalaf, epoch_length, Fs)./signal_energy(gamma_signalaf, epoch_length, Fs));
            EalphaBetaASM_af = (signal_energy(alpha_signalaf, epoch_length, Fs)./asm_signal(vector_af7,vector_af8,epoch_length, Fs));
            EEbeta_af = (energy_entropy(beta_signalaf, epoch_length, Fs));
            EEgamma_af = (energy_entropy(gamma_signalaf, epoch_length, Fs));
            EEtheta_af = (energy_entropy(theta_signalaf, epoch_length,Fs));
            DASMalpha_af = (dasm_signal(vector_af7, vector_af8, epoch_length, Fs));
            PSD_beta_af = (psd_signal(beta_signalaf, epoch_length, Fs, alpha_freq));
            
            EalphaGamma_tp = (signal_energy(alpha_signaltp, epoch_length, Fs)./signal_energy(gamma_signaltp, epoch_length, Fs));
            EalphaBetaASM_tp = (signal_energy(alpha_signaltp, epoch_length, Fs)./asm_signal(vector_tp9,vector_tp10,epoch_length, Fs));
            EEbeta_tp = (energy_entropy(beta_signaltp, epoch_length, Fs));
            EEgamma_tp = (energy_entropy(gamma_signaltp, epoch_length, Fs));
            EEtheta_tp = (energy_entropy(theta_signaltp, epoch_length,Fs));
            DASMalpha_tp = (dasm_signal(vector_tp9, vector_tp10, epoch_length, Fs));
            PSD_beta_tp = (psd_signal(beta_signaltp, epoch_length, Fs, alpha_freq));
            
            %datasetFolder = {pwd, "creating dataset"};
            modelFile = "D:\Neuro\Projeto\MindSpark\creating dataset\bestModelBoth.mat";
            load(modelFile,'bestClassifier','mu','sigma');
            
            features = [EalphaGamma_af' EalphaBetaASM_af' EEbeta_af' EEgamma_af' EEtheta_af' DASMalpha_af' PSD_beta_af' EalphaGamma_tp' EalphaBetaASM_tp' EEbeta_tp' EEgamma_tp' EEtheta_tp' DASMalpha_tp' PSD_beta_tp'];
            features = (features-mu)./sigma;
            [~, scores, ~] = predict(bestClassifier, features);

            val = floor(mean(scores(:,1)) * 100);
            assignin('base', 'scores', scores(:,1));
            assignin('base', 'val', val);

            stop(audio_player);
            delete(audio_player)

            scoreWindow = score;
            scoreFig = scoreWindow.UIFigure; 

            app.UIFigure.UserData.scoreFig = scoreFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end

        % Callback function
        function BackButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.MeditationoclockLabel = uilabel(app.UIFigure);
            app.MeditationoclockLabel.Text = 'Meditation  o''clock!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end